classdef cnnui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImageSegmentasi        matlab.ui.control.Image
        SegmentasiLabel        matlab.ui.control.Label
        PreprocessingButton    matlab.ui.control.Button
        ImageFokusROI          matlab.ui.control.Image
        RegionofInterestLabel  matlab.ui.control.Label
        ImageResize            matlab.ui.control.Image
        ImageGrayToRGB         matlab.ui.control.Image
        ImageResizeLabel       matlab.ui.control.Label
        GraytoRGBLabel         matlab.ui.control.Label
        PredictButton          matlab.ui.control.Button
        PredictionResultLabel  matlab.ui.control.Label
        TitleLabel             matlab.ui.control.Label
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = private)
        image; % Description
        prediction; % Description
        net % Description
        targetSize
        labels
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.net = load('cnnnet.mat', 'net').net;
            app.labels = load('cnnnet.mat', 'labels').labels;
            app.targetSize = [32 32];
        end

        % Callback function
        function TrainButtonPushed(app, event)
            imageFolder = 'food-101';
            inputSize = [32 32 3];
            numClasses = 101;
            numFiles = 0.1;
            numTrainFiles = 0.90;
            filterSize = 3;
            numFilters = 3;
            numChannels = 3;
            maxEpochs = 10;
            
            imds = imageDatastore(imageFolder, ...
                'IncludeSubfolders', true, ...
                'LabelSource', 'foldernames');
            
            [imdsUsed, imdsNotUsed] = splitEachLabel(imds, numFiles, 'randomized');
            clear imdsNotUsed
            
            [imdsTrain, imdsValidation] = splitEachLabel(imdsUsed, numTrainFiles, 'randomized');
            clear imdsUsed
            
            trainingData  = augmentedImageDatastore(app.targetSize, imdsTrain, 'ColorPreprocessing', 'gray2rgb');
            validationData  = augmentedImageDatastore(app.targetSize, imdsValidation, 'ColorPreprocessing', 'gray2rgb');
            
            layers = [
                imageInputLayer(inputSize)
                convolution2dLayer(filterSize, 6, 'NumChannels', 3)   % 2-D CNN
                batchNormalizationLayer
                reluLayer
                maxPooling2dLayer(2,Stride=2)
                convolution2dLayer(filterSize, 12 * 2, 'NumChannels', 6)   % 2-D CNN
                batchNormalizationLayer
                reluLayer
                fullyConnectedLayer(numClasses)
                softmaxLayer
                classificationLayer];
            
            options = trainingOptions('adam', ...
                'MaxEpochs', maxEpochs, ...
                'ValidationData', validationData, ...
                'ValidationFrequency', 10, ...
                'Verbose', false, ...
                'Plots', 'training-progress', ...
                'MiniBatchSize', 1280, ...
                'Shuffle', 'never', ...
                'InitialLearnRate', 0.01);
            
            app.net = trainNetwork(trainingData,layers,options);
            
            YPred = classify(app.net,validationData);
            YValidation = imdsValidation.Labels;
            accuracy = mean(YPred == YValidation);
            
            save cnnnet.mat app.net labels
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            [file, path] = uigetfile("*.*");
            if file == 0
                return
            end
            app.image = append(path, file);
            app.Image.ImageSource = app.image;
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            imds = imageDatastore(app.image, "IncludeSubfolders", false, 'LabelSource', 'foldernames');
%             imds = imread(app.image);
%             imds = imresize(imds, app.targetSize);
            imds = augmentedImageDatastore(app.targetSize, imds, ...
                "ColorPreprocessing", "gray2rgb");
            app.prediction = classify(app.net, imds);
            app.PredictionResultLabel.Text = app.prediction;
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            imds = imageDatastore(app.image, "IncludeSubfolders", false, 'LabelSource', 'foldernames');
            imageSize = size(imread(app.image));
            rgbImds = augmentedImageDatastore(imageSize, imds, ...
                "ColorPreprocessing", "gray2rgb");
            imds = augmentedImageDatastore(app.targetSize, imds, ...
                "ColorPreprocessing", "gray2rgb");
            a = rgbImds.read;
            a = a(1,'input');
            a = a.input;
            a = a{1,1};
            app.ImageGrayToRGB.ImageSource = a;
            a = imds.read;
            a = a(1,'input');
            a = a.input;
            a = a{1,1};
            app.ImageResize.ImageSource = a;

            imds.reset;

            % Buat area fokus ditengah gambar, dan semakin jauh dari tengah
            % semakin gelap.
            imageSize = 32;
            center = imageSize/2 + 1;
            [columnsInImage, rowsInImage] = meshgrid(1:imageSize, 1:imageSize);
            pixelCircle = (rowsInImage - center).^2 + (columnsInImage - center).^2;
            pixelCircle = (1 - pixelCircle./512);
            pixelCircle(pixelCircle < 0) = 0;
            weightedImage = double(rgb2gray(a)).*double(pixelCircle);

            % Membuat mask Region of Interest dengan metode Otsu 
            % Thresholding. 
            roiMask = uint8(imbinarize(rgb2gray(a), 'global'));

            % Deteksi dulu apakah tengah gambar, 
            % yang biasanya berupa objek makanannya, berwarna terang atau 
            % gelap. Jika objek ditengah berwarna gelap, maka balik mask 
            % ROI supaya memfilter warna terang sebagai background.
            if (mean(mean(weightedImage)) > mean(mean(pixelCircle)))
            else
                roiMask = uint8(roiMask == 0);
            end

            % Isi lobang-lobang pada mask ROI. Ini membuat bagian makanan
            % yang memiliki pencahayaan berbeda dari bagian lain makanan
            % supaya tidak terklasifikasi sebagai background.
            roiMask = imfill(roiMask, "holes");

            % Menampilkan gambar mask ROI dan hasil segmentasinya.
            app.ImageFokusROI.ImageSource = cat(3, cat(3, roiMask, roiMask), roiMask).* 255;
            segmentedImage(:,:,1) = a(:,:,1).*roiMask;
            segmentedImage(:,:,2) = a(:,:,2).*roiMask;
            segmentedImage(:,:,3) = a(:,:,3).*roiMask;
            app.ImageSegmentasi.ImageSource = segmentedImage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Clasifikasi Tipe Makanan';
            app.PredictionResultLabel = uilabel(app.UIFigure);
            app.PredictionResultLabel.Text = 'Prediction Result';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.GraytoRGBLabel = uilabel(app.UIFigure);
            app.GraytoRGBLabel.Text = 'Gray to RGB';
            app.ImageResizeLabel = uilabel(app.UIFigure);
            app.ImageResizeLabel.Text = 'Image Resize';
            app.ImageGrayToRGB = uiimage(app.UIFigure);
            app.ImageResize = uiimage(app.UIFigure);
            app.RegionofInterestLabel = uilabel(app.UIFigure);
            app.RegionofInterestLabel.Text = 'Region of Interest';
            app.ImageFokusROI = uiimage(app.UIFigure);
            app.PreprocessingButton = uibutton(app.UIFigure, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.SegmentasiLabel = uilabel(app.UIFigure);
            app.SegmentasiLabel.Text = 'Segmentasi';
            app.ImageSegmentasi = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cnnui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end